classdef controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LiveDataGrid             matlab.ui.container.GridLayout
        startButton              matlab.ui.control.Button
        rotationAngle            matlab.ui.control.Slider
        translation              matlab.ui.control.Label
        translationDis           matlab.ui.control.Slider
        rotation                 matlab.ui.control.Label
        light_ele_P              matlab.ui.control.Button
        light_azi_P              matlab.ui.control.Button
        light_H_P                matlab.ui.control.Button
        horizontalP              matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        light_ele_N              matlab.ui.control.Button
        light_azi_N              matlab.ui.control.Button
        light_H_N                matlab.ui.control.Button
        horizontalN              matlab.ui.control.Button
        cameraN                  matlab.ui.control.Button
        lightverticalLabel       matlab.ui.control.Label
        homeButton               matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        AltitudeLabel            matlab.ui.control.Label
        lightazimuthLabel        matlab.ui.control.Label
        lighthorizontalLabel     matlab.ui.control.Label
        SatellitesLabel          matlab.ui.control.Label
        ConfigPanel              matlab.ui.container.Panel
        ConfigGrid               matlab.ui.container.GridLayout
        FileLoggingPanel         matlab.ui.container.Panel
        FileLoggingGrid          matlab.ui.container.GridLayout
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        FileSelectButton         matlab.ui.control.Button
        FilenameEditField        matlab.ui.control.EditField
        FilenameLabel            matlab.ui.control.Label
        ConfigurationPanel       matlab.ui.container.Panel
        ConfigurationGrid        matlab.ui.container.GridLayout
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        BaudrateDropDown         matlab.ui.control.DropDown
        BaudRateLabel            matlab.ui.control.Label
        RefreshButton            matlab.ui.control.Button
        SerialportDropDown       matlab.ui.control.DropDown
        SerialportDropDownLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % App Flags
        ChecksumAlert = false           % Display warning for invalid checksum

        % Object Handles
        MapViewAxes                     % Handle to geoaxes object
        MapViewPlot                     % Handle to geoplot line object
        Plot                            % Handle to "Plot View" line object

        % File Selection and Logging
        Filename = "data.txt"       % Default file name for logging
        FilePath = pwd                  % Default file path for logging
        RawFilename = "data_raw.txt"% Default name for raw logging file
        SerialPort                      % Handle to serialport object
        Logging = false                 % Boolean for whether we are logging to file or not
        FileInitialized = false
        CycleEnd

        % for camera
        cam
        ImageHandle % imageオブジェクトのハンドル

    end
    
    properties (Access = public)
        CAMERA_TRANS
        LIGHT_AZI
        LIGHT_ELE
        LIGHT_H
        LIGHT_V
    end

    properties (Access = private)
        % --- GPS / NMEA data ---------------------------------------------
        % The live-display widgets that originally showed these values
        % (Live Data fields, Map/Plot/NMEA Log tabs) were removed from this
        % app. The data is still parsed from the serial port (processData)
        % and can be logged to file. These are (re)initialized in resetData.
        NMEARaw        = strings(1,0)   % Recent raw NMEA sentences (row)
        LastLines      = 100            % Max number of NMEA sentences to keep
        LastMinutes    = 10             % Keep DataTable rows from the last N min
        DataTable                       % Parsed data over time (see resetData)
        TableVariables = {'ComputerTime','FixType','Latitude','Longitude', ...
                          'Satellites','Altitude','HDOP','Course','Speed', ...
                          'UTCDate','UTCTime'}
        FixDict                         % (unused; live status display removed)
        FixType        = 0
        Latitude       = []
        Longitude      = []
        Altitude       = []
        HDOP           = []
        Course         = []
        Speed          = []
        Satellites     = []
        UTCDate        = ""
        UTCTime        = ""
        LatitudeTrack  = []
        LongitudeTrack = []
    end


    methods (Access = private)

        function serialportCallback(app,obj,evt)
            %serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states

            % Handle Data state
            inputline = readline(obj);
            % Extract callback timestamp from evt data
            eventtime = datetime(evt.AbsTime,Format='yyyy-MM-dd''T''HH:mm:ss.SSS');

            % Process Data state
            latestdata = processData(app,inputline,eventtime);

            if app.Logging
                % Log Data state
                logData(app,inputline,latestdata);
            end

            % Update Live View state
            updateLiveView(app)
        end

        function newrow = processData(app,inputline,dt)
            %processData Read and processes the newest line,
            %   save processed data to app properties for later use.
            %   This includes all of the "Live Data", "Track Data", meaning
            %   data saved over time, and the newest LastLines number of NMEA
            %   raw data sentences

            newrow = {dt};

            % Run checksum for inputline; if checksum is invalid, do not
            % process the line (return early)
            if ~validateChecksum(app,inputline)
                if app.ChecksumAlert
                    warning("Checksum is wrong for " + inputline);
                end
            end

            % Parse sentences
            if contains(inputline,"GPGGA") % Parse GGA sentence and save its output to app properties
                results = parseGGA(app,inputline);
                updateAppProperties(app,"GPGGA",results);
            elseif contains(inputline,"GPRMC") % Parse RMC sentence and save its output to app properties
                results = parseRMC(app,inputline);
                updateAppProperties(app,"GPRMC",results);

                % Update collected data over time if GPGGA sentence was also processed
                if ~isempty(app.Latitude)
                    newrow = {newrow{1}, app.FixType, app.Latitude, app.Longitude, app.Satellites, app.Altitude, app.HDOP, app.Course, app.Speed, app.UTCDate, app.UTCTime};
                    app.DataTable = [app.DataTable; newrow];

                    % Delete times older than 10 minutes
                    rowsToKeep = minutes(datetime("now") - app.DataTable.ComputerTime) < app.LastMinutes;
                    app.DataTable = app.DataTable(rowsToKeep,:);
                end
            end

            % Save NMEA raw data over time, with LastLines lines max
            if numel(app.NMEARaw) < app.LastLines
                app.NMEARaw = [inputline app.NMEARaw];
            else
                app.NMEARaw = circshift(app.NMEARaw,1);
                app.NMEARaw(1) = inputline;
            end
        end

        function lat = latToMap(~,inputlat,dir)
            %latToMap is a helper function to convert the latitude from the
            %   NMEA data (minute/second degrees) to decimal degrees.
            %   NMEA format is ddmm.mmmm, so use the degrees and minutes to
            %   convert to decimal degrees.

            if isempty(inputlat)
                lat = NaN;
                return
            end
            % Extract degrees from first 2 characters
            deg = str2double(extractBefore(inputlat,3));
            % Extract minutes from all characters following the 2nd
            min = str2double(extractAfter(inputlat,2));
            lat = deg + min/60;
            if dir == "S" % if direction is South
                lat = -lat;
            end
        end

        function long = longToMap(~,inputlong,dir)
            %longToMap is a helper function to convert the longitude from the
            %   NMEA data (minute/second degrees) to decimal degrees.
            %   NMEA format is (d)ddmm.mmmm, so use the degrees and minutes to
            %   convert to decimal degrees.

            if isempty(inputlong)
                long = NaN;
                return
            end
            % Extract degrees from first 3 characters
            deg = str2double(extractBefore(inputlong,4));
            % Extract minutes from all characters following the 3rd
            min = str2double(extractAfter(inputlong,3));
            long = deg + min/60;
            if dir == "W" % ifs direction is West
                long = -long;
            end
        end

        function kmh = knotsToKMH(~, knots)
            %knotsToKMH is a helper function to convert the speed in knots
            %   to kilometers per hour

            if isempty(knots)
                kmh = NaN;
                return
            end
            kmh = str2double(knots)*1.852;
        end

        function updateAppProperties(app,sentence,values)
            %updateAppProperties Update the app properties for the input
            %   sentence based on the given values. For example, if sentence
            %   was "GPGGA", then the GPGGA-related app properties will be
            %   updated. values contains a struct of the necessary data.
            switch sentence
                case "GPGGA"
                    app.FixType = values.FixType;
                    app.Satellites = values.Satellites;
                    if app.Logging
                        % If Logging, preserve data for map
                        app.LatitudeTrack(end+1) = values.Latitude;
                        app.LongitudeTrack(end+1) = values.Longitude;
                    end
                    app.Latitude = values.Latitude;
                    app.Longitude = values.Longitude;
                    app.Altitude = values.Altitude;
                    app.HDOP = values.HDOP;
                case "GPRMC"
                    app.Course = values.Course;
                    app.Speed = values.Speed;
                    app.UTCDate = values.UTCDate;
                    app.UTCTime = values.UTCTime;
                    % Since the GPRMC line is last, flag end of data cycle
                    app.CycleEnd = 1;
            end
        end

        function updateLiveView(app)
            %updateLiveView No-op. The live-display widgets (Live Data
            %   fields and the Map View / Plot View / NMEA Log tabs) were
            %   removed from this app, so there is nothing to refresh here.
            %   GPS data is still parsed into app properties (processData)
            %   and, when logging is on, written to file (logData).
            app.CycleEnd = 0;   % consume the data-cycle flag
        end

        function setAppState(app,state)
            %setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialportDropDown.Enable = "on";
                    app.BaudrateDropDown.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.RefreshButton.Enable = "on";

                    app.DisconnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    % app.XDataDropDown.Enable = "on";
                    % app.YDataDropDown.Enable = "on";
                    % app.FilterByDropDown.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "WaitForData"
                    % Not logging
                    app.DisconnectButton.Enable = "on";
                    app.StartButton.Enable = "on";
                    app.FilenameEditField.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StopButton.Enable = "off";
                    app.RefreshButton.Enable = "off";
                case "Logging"
                    app.DisconnectButton.Enable = "on";
                    app.StopButton.Enable = "on";

                    app.SerialportDropDown.Enable = "off";
                    app.BaudrateDropDown.Enable = "off";
                    app.ConnectButton.Enable = "off";
                    app.StartButton.Enable = "off";
                    app.FilenameEditField.Enable = "off";
                    app.RefreshButton.Enable = "off";
            end
        end

        function results = validateChecksum(~,inputline)
            %validateChecksum confirms that the latest line has a valid
            %   checksum calculation

            if ~startsWith(inputline,"$")
                results = 0;
                return
            end
            splitline = split(inputline,["$" "*"]);
            checksumline = uint8(splitline{2});
            expected = hex2dec(splitline(3));
            checksum = uint8(0);
            % Iterate through each character in checksumline, executing a
            % bit-wise XOR across all characters to validate the checksum
            for character = checksumline
                checksum = bitxor(checksum,character);
            end
            results = checksum == expected;
        end

        function updateNMEALog(app)
            %updateNMEALog is a helper function for the NMEA Log tab

            switch app.FilterByDropDown.Value
                case "All"
                    app.NMEAStringTextArea.Value = app.NMEARaw;
                case "$GPGGA"
                    filterby = startsWith(app.NMEARaw,"$GPGGA");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
                case "$GPRMC"
                    filterby = startsWith(app.NMEARaw,"$GPRMC");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
                case "$GPGSV"
                    filterby = startsWith(app.NMEARaw,"$GPGSV");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
                case "$GPGLL"
                    filterby = startsWith(app.NMEARaw,"$GPGLL");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
                case "$GPVTG"
                    filterby = startsWith(app.NMEARaw,"$GPVTG");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
                case "$GPGSA"
                    filterby = startsWith(app.NMEARaw,"$GPGSA");
                    app.NMEAStringTextArea.Value = app.NMEARaw(filterby);
            end
        end

        function updateMapView(app,logging)
            %updateMapView is a helper function for the Map View tab

            if logging
                latdata = app.LatitudeTrack;
                longdata = app.LongitudeTrack;
            else
                latdata = app.Latitude;
                longdata = app.Longitude;
            end
            set(app.MapViewPlot,"LatitudeData",latdata,"LongitudeData",longdata);
            geolimits(app.MapViewAxes,app.GeolimitsDropDown.Value)
        end

        function updatePlotView(app)
            %updatePlotView is a helper function for the Plot View tab

            filter = seconds(datetime("now")-app.DataTable.ComputerTime) < app.XDataDropDown.Value;
            x = app.DataTable.ComputerTime(filter);
            y = app.DataTable{filter,app.YDataDropDown.Value};
            set(app.Plot,"XData",x,"YData",y);
        end


        function datastruct = parseGGA(app,inputline)
            %parseGGA is a helper function to read data from GPGGA sentences
            %    parseGGA takes in a serialport line beginning with "$GPGGA"
            %    and returns the latitude, longitude, HDOP, altitude, and number
            %    of satellites in use as a struct.
            %    For example, consider the following line:
            %    $GPGGA,170928.000,4217.9742,N,07121.1046,W,2,07,1.70,66.6,M,-33.8,M,0000,0000*64
            %       (1) $GPGGA      Sentence type
            %       (2) 170928.000  UTC time
            %       (3) 4217.9742   Latitude
            %       (4) N           North/South, latitude direction
            %       (5) 07121.1046  Longitude
            %       (6) W           East/West, longitude direction
            %       (7) 2           Quality indicator/fix type
            %       (8) 07          Number of satellites in use
            %       (9) 1.70        HDOP
            %       (10) 66.6       Altitude

            datastruct = struct("Latitude",NaN,"Longitude",NaN,"FixType",NaN,"HDOP",NaN,"Altitude",NaN,"Satellites",NaN);
            splitdata = split(inputline,",");
            datastruct.FixType = str2double(splitdata{7});
            if datastruct.FixType > 0
                datastruct.Latitude = latToMap(app,splitdata{3},splitdata{4});
                datastruct.Longitude = longToMap(app,splitdata{5},splitdata{6});
                datastruct.HDOP = str2double(splitdata{9});
                datastruct.Altitude = str2double(splitdata{10});
            end
            datastruct.Satellites = str2double(splitdata{8});
        end

        function datastruct = parseRMC(app,inputline)
            %parseRMC is a helper function to read data from GPRMC sentences
            %    parseRMC takes in a serialport line beginning with "$GPRMC"
            %    and returns the speed, course, UTC date, and UTC time from the
            %    GPS as a struct.
            %    For example, consider the following line:
            %    $GPRMC,170926.000,A,4217.9736,N,07121.1043,W,0.56,351.07,010124,,,D*78
            %       (1) $GPRMC      Sentence type
            %       (2) 170926.000  UTC time
            %       (3) A           Position status
            %       (4) 4217.9736   Latitude
            %       (5) N           Latitude direction
            %       (6) 07121.1043  Longitude
            %       (7) W           Longitude direction
            %       (8) 0.56        Speed over ground in knots
            %       (9) 351.07      Course in degrees True
            %       (10) 010124     UTC date

            datastruct = struct("Course",NaN,"Speed",NaN,"UTCDate",NaN,"UTCTime",NaN);
            splitdata = split(inputline,",");
            if splitdata{3} == "A" % If there is a fix, indicated by status "A" for active, "V" for void
                datastruct.Speed = knotsToKMH(app,splitdata{8});
                datastruct.Course = str2double(splitdata{9});
            end
            if ~isempty(splitdata{10}) && ~isempty(splitdata{2}) % Only proceed if there is a date and time
                if contains(splitdata{2},".")
                    % Time with fractional seconds
                    timeFormat = "ddMMyy'T'HHmmss.SSS";
                else
                    % Time without fractional seconds
                    timeFormat = "ddMMyy'T'HHmmss";
                end
                dt = datetime([splitdata{10} 'T' splitdata{2}],InputFormat=timeFormat);
                splittime = split(string(dt));
                datastruct.UTCDate = splittime(1);
                datastruct.UTCTime = splittime(2);
            end
        end

        function logData(app,inputline,row)
            %logData Represents the Log Data state for app. If FileInitialized is
            %   false, initialize files and log to file. Otherwise, just log to
            %   file

            if ~app.FileInitialized
                initializeProcessedDataFile(app);
            end
            logToFile(app,inputline,row);
        end

        function logToFile(app,inputline,row)
            %logToFile Write to raw data file and processed data file. Because
            %   inputline is a single line, use writelines for the raw file.
            %   Because row is a cell array, use writecell for processed file.

            % Log raw data
            dt = row{1};
            writelines(string(dt)+" "+inputline,app.RawFilename,WriteMode="append")

            % Log parsed data
            if app.CycleEnd
                writecell(row,app.FilenameEditField.Value,WriteMode="append")
            end
        end

        function initializeProcessedDataFile(app)
            %initializeProcessedDataFile Write header to file that logs all
            %   data (not raw file). If there is an error, disable logging
            %   and display error. Allow file logging with FileInitialized.

            try
                writecell(app.TableVariables,app.FilenameEditField.Value,WriteMode="append")
                app.FileInitialized = true;
            catch ME
                uialert(app.UIFigure,ME.message,"File Error",Interpreter="html")
                app.Logging = false;
            end
        end

        function [processedfile, rawfile] = createFileName(app,filename)
            %createFileName Check if the file name and corresponding raw file
            %   name meets expected format, meaning its extension is .TXT and
            %   it is not an already existing file. If the file name exists,
            %   return new, auto-indexed name.
            %   For example, if '<datafolder>\GPS_data.txt' exists, return the
            %   next indexed file name in the sequence that does not exist,
            %   such as '<datafolder>\GPS_data_2.txt'

            [filepath, name, ext] = fileparts(filename);
            if ~strcmpi(ext,".txt")
                uialert(app.UIFigure,"File must be in .TXT format","File Log Error")
                ext = ".txt";
            end
            ind = 0;
            processedfile = fullfile(filepath,strcat(name,ext));
            rawfile = fullfile(filepath,strcat(name,"_raw",ext));
            while isfile(processedfile) || isfile(rawfile)
                ind = ind + 1;
                processedfile = fullfile(filepath,strcat(name,"_",num2str(ind),ext));
                rawfile = fullfile(filepath,strcat(name,"_",num2str(ind),"_raw",ext));
            end
        end

        function updateLiveDataFields(app)
            %updateLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to their respective app property
            %   values. Enable the lamp indicating GPS status (valid fix)

            app.GPSStatusLamp.Color = [0 1 0];
            app.GPSStatusField.Value = app.FixDict(app.FixType);
            app.SatellitesField.Value = string(app.Satellites);
            app.LatitudeField.Value = string(app.Latitude);
            app.LongitudeField.Value = string(app.Longitude);
            app.AltitudeField.Value = string(app.Altitude);
            app.CourseField.Value = string(app.Course);
            app.SpeedField.Value = string(app.Speed);
            app.HDOPField.Value = string(app.HDOP);
            app.UTCDateField.Value = app.UTCDate;
            app.UTCTimeField.Value = app.UTCTime;
        end

        function clearLiveDataFields(app)
            %clearLiveDataFields Set all of the edit fields in the Live Data
            %   view section of the app to be empty values. Disable the lamp
            %   indicating GPS status (no fix), since there is no data to show.

            app.GPSStatusLamp.Color = [0.9 0.9 0.9];
            app.GPSStatusField.Value = app.FixDict(0);
            app.SatellitesField.Value = "-";
            app.LatitudeField.Value = "-";
            app.LongitudeField.Value = "-";
            app.AltitudeField.Value = "-";
            app.CourseField.Value = "-";
            app.SpeedField.Value = "-";
            app.HDOPField.Value = "-";
            app.UTCDateField.Value = app.UTCDate;
            app.UTCTimeField.Value = app.UTCTime;
        end

        function resetData(app)
            %resetData Reset all of the saved data from the app properties.

            app.NMEARaw        = strings(1,0);
            app.LatitudeTrack  = [];
            app.LongitudeTrack = [];
            app.FixType        = 0;
            % Empty, type-stable table so that [app.DataTable; newrow] in
            % processData appends correctly and app.DataTable.ComputerTime
            % stays a datetime column.
            app.DataTable = table('Size',[0 numel(app.TableVariables)], ...
                'VariableTypes',[{'datetime'} repmat({'double'},1,8) {'string'} {'string'}], ...
                'VariableNames',app.TableVariables);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set up other components
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
            app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);            
            setAppState(app,"DeviceSelection");

            % set costants
            app.CAMERA_TRANS = 1 / 0.054; % pulse/mm
            app.LIGHT_AZI = 1 / 0.03375; % pulse/deg
            app.LIGHT_ELE = 1 / 0.03374; % pulse/deg
            app.LIGHT_H = 1 / 0.012; % pulse/deg
            app.LIGHT_V = 1 / 0.006; % pulse/mm
            
            app.cam = webcam("HD USB Camera");            % 必要なら名前で指定

            % UIAxesに空の画像を作成
            app.ImageHandle = image(app.UIAxes, zeros(1080,1920,3,'uint8'));

            % 軸を非表示に設定
            % axis(app.UIAxes, 'off');
            % app.UIAxes.Toolbar.Visible = 'off';

            % プレビューを開始
            preview(app.cam, app.ImageHandle);


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            setAppState(app,"Connected");
            drawnow

            % Validate selected serial port device
            try
                app.SerialPort = serialport(app.SerialportDropDown.Value,str2double(app.BaudrateDropDown.Value));
            catch ME
                uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                setAppState(app,"DeviceSelection");
                return
            end

            % Set terminator and callback
            configureTerminator(app.SerialPort,"CR/LF")
            configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))

            % Reset app properties
            resetData(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.SerialportDropDown.Items = cellstr(["" serialportlist]);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.SerialPort)
            StopButtonPushed(app);
            setAppState(app,"DeviceSelection");
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            [app.Filename, app.FilePath] = uiputfile(".txt");
            figure(app.UIFigure)
            if ~isequal(app.Filename,0) && ~isequal(app.FilePath,0)
                app.FilenameEditField.Value = fullfile(app.FilePath,app.Filename);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If there is no file initialized, make sure to check for a
            % valid file name before starting to log
            if ~app.FileInitialized
                [name, raw] = createFileName(app,app.FilenameEditField.Value);
                app.FilenameEditField.Value = name;
                app.RawFilename = raw;
            end
            
            app.Logging = true;

            % Reset Map View track data
            app.LatitudeTrack = [];
            app.LongitudeTrack = [];
            setAppState(app,"Logging");
        end

        % Callback function
        function FilterByDropDownValueChanged(app, event)
            updateNMEALog(app);
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab
                case "MapViewTab"
                    updateMapView(app,app.Logging);
                case "PlotViewTab"
                    updatePlotView(app);
                case "NMEALogTab"
                    updateNMEALog(app);
            end
        end

        % Callback function
        function XDataDropDownValueChanged(app, event)
            updatePlotView(app);
        end

        % Callback function
        function YDataDropDownValueChanged(app, event)
            ylabel(app.UIAxes,app.YDataDropDown.Value)
            updatePlotView(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Logging = false;
            % Do not change FileInitialized, as keeping the file name
            % will mean you continue logging to the same file
            setAppState(app,"WaitForData");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = "";
            if ~isempty(app.SerialPort) && isvalid(app.SerialPort)
                selection = uiconfirm(app.UIFigure,"Close app and serial port connection?","Confirm Close");
            end
            if selection ~= "Cancel"
                delete(app)
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            [name, raw] = createFileName(app,app.FilenameEditField.Value);
            % Reset FileInitialized only when the file name is changed
            % if the file name is not changed, continue to write to the
            % existing file, appending new data to the end
            app.FileInitialized = false;
            app.FilenameEditField.Value = name;
            app.RawFilename = raw;
        end

        % Button pushed function: resetButton
        function reset(app, event)
            writeline(app.SerialPort, "R");  
        end

        % Button pushed function: homeButton
        function home(app, event)
            writeline(app.SerialPort, "H");    % 末尾に "\r\n" が付加される
        end

        % Button pushed function: Button_6
        function cameraP(app, event)
            tmp = round(app.CAMERA_TRANS * app.translationDis.Value);
            command = sprintf('M1+%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: cameraN
        function cameraNPushed(app, event)
            tmp = round(app.CAMERA_TRANS * app.translationDis.Value);
            command = sprintf('M1-%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: horizontalN
        function verticalNButtonPushed(app, event)
            tmp = round(app.LIGHT_V * app.translationDis.Value);
            tmp = round(1/0.054 * app.translationDis.Value);
            command = sprintf('M3-%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: horizontalP
        function horizontalPButtonPushed(app, event)
            tmp = round(app.LIGHT_V * app.translationDis.Value);
            tmp = round(1/0.054 * app.translationDis.Value);
            command = sprintf('M3+%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: light_H_P
        function light_H_PButtonPushed(app, event)
            tmp = round(app.LIGHT_H * app.rotationAngle.Value);
            command = sprintf('M2-%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: light_H_N
        function light_H_NButtonPushed(app, event)
            tmp = round(app.LIGHT_H * app.rotationAngle.Value);
            command = sprintf('M2+%05d', tmp);            
            writeline(app.SerialPort, command);

        end

        % Button pushed function: light_azi_P
        function light_azi_PButtonPushed(app, event)
            tmp = round(app.LIGHT_AZI * app.rotationAngle.Value);
            command = sprintf('M5-%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: light_azi_N
        function light_azi_NButtonPushed(app, event)
            tmp = round(app.LIGHT_AZI * app.rotationAngle.Value);
            command = sprintf('M5+%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: light_ele_P
        function light_ele_PButtonPushed(app, event)
            tmp = round(app.LIGHT_ELE * app.rotationAngle.Value);
            command = sprintf('M4+%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: light_ele_N
        function light_ele_NButtonPushed(app, event)
            tmp = round(app.LIGHT_ELE * app.rotationAngle.Value);
            command = sprintf('M4-%05d', tmp);            
            writeline(app.SerialPort, command);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            mainExp(app.cam, app.ImageHandle)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'preview')
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.ConfigurationPanel = uipanel(app.ConfigGrid);
            app.ConfigurationPanel.Title = 'Configuration';
            app.ConfigurationGrid = uigridlayout(app.ConfigurationPanel);
            app.SerialportDropDownLabel = uilabel(app.ConfigurationGrid);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConfigurationGrid);
            app.SerialportDropDown.Items = {};
            app.SerialportDropDown.Placeholder = 'Select...';
            app.SerialportDropDown.Value = {};
            app.RefreshButton = uibutton(app.ConfigurationGrid, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '';
            app.BaudRateLabel = uilabel(app.ConfigurationGrid);
            app.BaudRateLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.ConfigurationGrid);
            app.BaudrateDropDown.Items = {'4800', '9600', '19200', '38400', '115200'};
            app.BaudrateDropDown.Editable = 'on';
            app.BaudrateDropDown.Placeholder = 'Select...';
            app.BaudrateDropDown.Value = '38400';
            app.ConnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConfigurationGrid, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.FileLoggingPanel = uipanel(app.ConfigGrid);
            app.FileLoggingPanel.Title = 'File Logging';
            app.FileLoggingGrid = uigridlayout(app.FileLoggingPanel);
            app.FilenameLabel = uilabel(app.FileLoggingGrid);
            app.FilenameLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileLoggingGrid, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'GPS_data.txt';
            app.FileSelectButton = uibutton(app.FileLoggingGrid, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Text = '';
            app.StartButton = uibutton(app.FileLoggingGrid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.FileLoggingGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.LiveDataGrid = uigridlayout(app.GridLayout);
            app.SatellitesLabel = uilabel(app.LiveDataGrid);
            app.SatellitesLabel.Text = 'camera';
            app.lighthorizontalLabel = uilabel(app.LiveDataGrid);
            app.lighthorizontalLabel.Text = 'light horizontal';
            app.lightazimuthLabel = uilabel(app.LiveDataGrid);
            app.lightazimuthLabel.Text = 'light azimuth';
            app.AltitudeLabel = uilabel(app.LiveDataGrid);
            app.AltitudeLabel.Text = 'light elevation';
            app.resetButton = uibutton(app.LiveDataGrid, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.resetButton.Text = 'reset';
            app.homeButton = uibutton(app.LiveDataGrid, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @home, true);
            app.homeButton.Text = 'home';
            app.lightverticalLabel = uilabel(app.LiveDataGrid);
            app.lightverticalLabel.Text = 'light vertical';
            app.cameraN = uibutton(app.LiveDataGrid, 'push');
            app.cameraN.ButtonPushedFcn = createCallbackFcn(app, @cameraNPushed, true);
            app.cameraN.Text = '–';
            app.horizontalN = uibutton(app.LiveDataGrid, 'push');
            app.horizontalN.ButtonPushedFcn = createCallbackFcn(app, @verticalNButtonPushed, true);
            app.horizontalN.Text = '–';
            app.light_H_N = uibutton(app.LiveDataGrid, 'push');
            app.light_H_N.ButtonPushedFcn = createCallbackFcn(app, @light_H_NButtonPushed, true);
            app.light_H_N.Text = '–';
            app.light_azi_N = uibutton(app.LiveDataGrid, 'push');
            app.light_azi_N.ButtonPushedFcn = createCallbackFcn(app, @light_azi_NButtonPushed, true);
            app.light_azi_N.Text = '–';
            app.light_ele_N = uibutton(app.LiveDataGrid, 'push');
            app.light_ele_N.ButtonPushedFcn = createCallbackFcn(app, @light_ele_NButtonPushed, true);
            app.light_ele_N.Text = '–';
            app.Button_6 = uibutton(app.LiveDataGrid, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @cameraP, true);
            app.Button_6.Text = '+';
            app.horizontalP = uibutton(app.LiveDataGrid, 'push');
            app.horizontalP.ButtonPushedFcn = createCallbackFcn(app, @horizontalPButtonPushed, true);
            app.horizontalP.Text = '+';
            app.light_H_P = uibutton(app.LiveDataGrid, 'push');
            app.light_H_P.ButtonPushedFcn = createCallbackFcn(app, @light_H_PButtonPushed, true);
            app.light_H_P.Text = '+';
            app.light_azi_P = uibutton(app.LiveDataGrid, 'push');
            app.light_azi_P.ButtonPushedFcn = createCallbackFcn(app, @light_azi_PButtonPushed, true);
            app.light_azi_P.Text = '+';
            app.light_ele_P = uibutton(app.LiveDataGrid, 'push');
            app.light_ele_P.ButtonPushedFcn = createCallbackFcn(app, @light_ele_PButtonPushed, true);
            app.light_ele_P.Text = '+';
            app.rotation = uilabel(app.LiveDataGrid);
            app.rotation.Text = 'rotation [deg]';
            app.translationDis = uislider(app.LiveDataGrid);
            app.translationDis.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.translationDis.Step = 5;
            app.translation = uilabel(app.LiveDataGrid);
            app.translation.Text = 'translation [mm]';
            app.rotationAngle = uislider(app.LiveDataGrid);
            app.rotationAngle.Limits = [0 30];
            app.rotationAngle.MajorTicks = [0 5 10 15 20 25 30];
            app.rotationAngle.Step = 5;
            app.startButton = uibutton(app.LiveDataGrid, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end